classdef frame2d_lab_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FrameUIAxes                matlab.ui.control.UIAxes
        ControlesPanel             matlab.ui.container.Panel
        d3SliderLabel              matlab.ui.control.Label
        d3Slider                   matlab.ui.control.Slider
        d2SliderLabel              matlab.ui.control.Label
        d2Slider                   matlab.ui.control.Slider
        d1SliderLabel              matlab.ui.control.Label
        d1Slider                   matlab.ui.control.Slider
        d1EditField                matlab.ui.control.NumericEditField
        d2EditField                matlab.ui.control.NumericEditField
        d3EditField                matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel  matlab.ui.control.Label
        ScaleFactorEditField       matlab.ui.control.NumericEditField
        mmLabel                    matlab.ui.control.Label
        mmLabel_2                  matlab.ui.control.Label
        mmLabel_3                  matlab.ui.control.Label
        hEditField                 matlab.ui.control.NumericEditField
        mmLabel_4                  matlab.ui.control.Label
        hLabel                     matlab.ui.control.Label
        hSlider                    matlab.ui.control.Slider
        Panel                      matlab.ui.container.Panel
        PesoLabel                  matlab.ui.control.Label
        gLabel                     matlab.ui.control.Label
        StressUIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        elem_list
        node_list
        dd
        temp_d1_value = 0
        temp_d2_value = 0
        temp_d3_value = 0
        temp_h_value = 0
        scale_factor % Description
    end

    methods (Access = private)
    
        function plotStressBarGraph(app)
            num_elems = app.elem_list.num_elems;
            x = linspace(1,num_elems,num_elems);
            sigma_esc_y = zeros(1,num_elems);
            sigma_eq_y = zeros(1,num_elems);
            for i = 1 : num_elems
                sigma_esc_y(i) = app.elem_list.getElemSigmaEsc(i);
                sigma_eq_y(i) = app.elem_list.getElemSigmaEq(i);
            end
            w1 = 1;
            w2 = 0.5;
            bar(app.StressUIAxes,x,sigma_esc_y,w1,'FaceColor',[0.2 0.2 0.5]);
            hold(app.StressUIAxes, 'on');
            bar(app.StressUIAxes,x,sigma_eq_y,w2,'FaceColor',[0 0.7 0.7]);
            hold(app.StressUIAxes, 'off');
            %grid on
            axis(app.StressUIAxes,[1-w1/2 num_elems+w1/2 0 max([max(sigma_eq_y) max(sigma_esc_y)])*1.1]);            
            legend(app.StressUIAxes,{'\sigma_{esc}','Elementos'})
        end
        
        function plotOriginalFrame(app)
            for ElemNum = 1 : app.elem_list.num_elems
                n_i_coords = app.elem_list.getNiCoords(ElemNum);
                n_f_coords = app.elem_list.getNfCoords(ElemNum);
                
                fill(app.FrameUIAxes,[n_i_coords(1);n_f_coords(1)], [n_i_coords(2);n_f_coords(2)], ...
                [.5 .5 .5], 'EdgeColor', [.7 .7 .7], 'LineWidth', 1);
            end         
        end
        
        function plotStressFrame(app)
            cla(app.FrameUIAxes);
            hold(app.FrameUIAxes, 'on');
            app.plotOriginalFrame();
            
            new_node_coords = app.node_list.getNodesNewCoords(app.scale_factor);
            new_elems_coords = app.elem_list.getElemsNewCoords(new_node_coords);
            
            % plots stresses on elements of the deformaded mesh
            for ElemNum = 1 : app.elem_list.num_elems
                sigma_eq = app.elem_list.getElemSigmaEq(ElemNum);
                fill(app.FrameUIAxes,[new_elems_coords(ElemNum,1);new_elems_coords(ElemNum,3)], ...
                    [new_elems_coords(ElemNum,2);new_elems_coords(ElemNum,4)], ...
                    [sigma_eq sigma_eq], ...
                    'FaceColor', 'interp', 'EdgeColor', 'interp', 'LineWidth', 1);
            end
        
            % plot nodes of the deformed mesh
            plot(app.FrameUIAxes,new_node_coords(:,1), new_node_coords(:,2), ...
                'o','MarkerEdgeColor','w','MarkerFaceColor','k','MarkerSize',3,'LineWidth',1);
            
            hold(app.FrameUIAxes, 'off');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, elem_list, node_list, d1, d2, d3, scale_factor, dd, d_min, d_max, h)
            app.elem_list = elem_list;
            app.node_list = node_list;
            app.d3Slider.Limits = [d_min d_max];
            app.d3Slider.Value = d3;
            app.d3EditField.Value = d3;
            app.d2Slider.Limits = [d_min d_max];
            app.d2Slider.Value = d2;
            app.d2EditField.Value = d2;
            app.d1Slider.Limits = [d_min d_max];
            app.d1Slider.Value = d1;
            app.d1EditField.Value = d1;
            app.hSlider.Limits = [120 360];
            app.hSlider.Value = h;
            app.hEditField.Value = h;
            app.scale_factor = scale_factor;
            app.ScaleFactorEditField.Value = scale_factor;
            app.dd = dd;
            
            app.plotStressBarGraph();
            maxHe = elem_list.getMaxAbsHe();
            minX = node_list.getMinX();
            minY = node_list.getMinY();
            maxX = node_list.getMaxX();
            maxY = node_list.getMaxY();
            
            axis(app.FrameUIAxes,[minX - .5 * maxHe, maxX + .5 * maxHe, minY - .5 * maxHe, maxY + .5 * maxHe]);
            box(app.FrameUIAxes,'on');
            app.plotStressFrame();
            app.gLabel.Text = strcat(num2str(app.elem_list.getTotalWeight*1000),'g');
        end

        % Value changing function: d3Slider
        function d3SliderValueChanging(app, event)
            changingValue = event.Value;
            newValue = round(changingValue/app.dd) * app.dd;
            app.d3EditField.Value = newValue;
            if newValue ~= app.temp_d3_value
                app.temp_d3_value = newValue;
                app.elem_list.updateExtDiameterOfGroup(3, newValue/1000);
                frame2d_solver(app.node_list, app.elem_list);
                app.plotStressBarGraph();
                app.plotStressFrame();
                app.gLabel.Text = strcat(num2str(app.elem_list.getTotalWeight*1000),'g');
            end
        end

        % Value changing function: d2Slider
        function d2SliderValueChanging(app, event)
            changingValue = event.Value;
            newValue = round(changingValue/app.dd) * app.dd;
            app.d2EditField.Value = newValue;
            if newValue ~= app.temp_d2_value
                app.temp_d2_value = newValue;
                app.elem_list.updateExtDiameterOfGroup(2, newValue/1000);               
                frame2d_solver(app.node_list, app.elem_list);
                app.plotStressBarGraph();
                app.plotStressFrame();
                app.gLabel.Text = strcat(num2str(app.elem_list.getTotalWeight*1000),'g');
            end
        end

        % Value changing function: d1Slider
        function d1SliderValueChanging(app, event)
            changingValue = event.Value;
            newValue = round(changingValue/app.dd) * app.dd;
            app.d1EditField.Value = newValue;
            if newValue ~= app.temp_d1_value
                app.temp_d1_value = newValue;
                app.elem_list.updateExtDiameterOfGroup(1, newValue/1000);
                frame2d_solver(app.node_list, app.elem_list);
                app.plotStressBarGraph();
                app.plotStressFrame();
                app.gLabel.Text = strcat(num2str(app.elem_list.getTotalWeight*1000),'g');
            end
        end

        % Value changed function: ScaleFactorEditField
        function ScaleFactorEditFieldValueChanged(app, event)
            app.scale_factor = app.ScaleFactorEditField.Value;
            app.plotStressFrame();
        end

        % Value changing function: hSlider
        function hSliderValueChanging(app, event)
            changingValue = event.Value;
            newValue = round(changingValue/app.dd) * app.dd;
            app.hEditField.Value = newValue;
            if newValue ~= app.temp_h_value
                app.temp_h_value = newValue;
                
                new_x = 0.500 - newValue/1000*0.250/sqrt(0.250^2+0.400^2);
                new_y = newValue/1000*0.400/sqrt(0.250^2+0.400^2);
                app.node_list.updateNode(7, new_x, new_y);                
                app.elem_list.updateElementsByNodeNum(7);
                
                frame2d_solver(app.node_list, app.elem_list);
                app.plotStressBarGraph();
                app.plotStressFrame();
                app.gLabel.Text = strcat(num2str(app.elem_list.getTotalWeight*1000),'g');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FrameUIAxes = uiaxes(app.UIFigure);
            title(app.FrameUIAxes, 'Frame')
            xlabel(app.FrameUIAxes, 'X [m]')
            ylabel(app.FrameUIAxes, 'Y [m]')
            app.ControlesPanel = uipanel(app.UIFigure);
            app.ControlesPanel.Title = 'Controles';
            app.d3SliderLabel = uilabel(app.ControlesPanel);
            app.d3SliderLabel.Text = 'd3';
            app.d3Slider = uislider(app.ControlesPanel);
            app.d3Slider.ValueChangingFcn = createCallbackFcn(app, @d3SliderValueChanging, true);
            app.d2SliderLabel = uilabel(app.ControlesPanel);
            app.d2SliderLabel.Text = 'd2';
            app.d2Slider = uislider(app.ControlesPanel);
            app.d2Slider.ValueChangingFcn = createCallbackFcn(app, @d2SliderValueChanging, true);
            app.d1SliderLabel = uilabel(app.ControlesPanel);
            app.d1SliderLabel.Text = 'd1';
            app.d1Slider = uislider(app.ControlesPanel);
            app.d1Slider.ValueChangingFcn = createCallbackFcn(app, @d1SliderValueChanging, true);
            app.d1EditField = uieditfield(app.ControlesPanel, 'numeric');
            app.d1EditField.Editable = 'off';
            app.d2EditField = uieditfield(app.ControlesPanel, 'numeric');
            app.d2EditField.Editable = 'off';
            app.d3EditField = uieditfield(app.ControlesPanel, 'numeric');
            app.d3EditField.Editable = 'off';
            app.ScaleFactorEditFieldLabel = uilabel(app.ControlesPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.ScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleFactorEditFieldValueChanged, true);
            app.mmLabel = uilabel(app.ControlesPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.ControlesPanel);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.ControlesPanel);
            app.mmLabel_3.Text = 'mm';
            app.hEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.hEditField.Editable = 'off';
            app.mmLabel_4 = uilabel(app.ControlesPanel);
            app.mmLabel_4.Text = 'mm';
            app.hLabel = uilabel(app.ControlesPanel);
            app.hLabel.Text = 'h';
            app.hSlider = uislider(app.ControlesPanel);
            app.hSlider.ValueChangingFcn = createCallbackFcn(app, @hSliderValueChanging, true);
            app.Panel = uipanel(app.UIFigure);
            app.PesoLabel = uilabel(app.Panel);
            app.PesoLabel.Text = 'Peso:';
            app.gLabel = uilabel(app.Panel);
            app.gLabel.Text = '0g';
            app.StressUIAxes = uiaxes(app.UIFigure);
            title(app.StressUIAxes, 'Stress')
            xlabel(app.StressUIAxes, 'Elements')
            ylabel(app.StressUIAxes, 'Stress [N.m^2]')
        end
    end

    methods (Access = public)

        % Construct app
        function app = frame2d_lab_app(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end